classdef truck_schedule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        AlgorithmListBox              matlab.ui.control.ListBox
        AlgorithmListBoxLabel         matlab.ui.control.Label
        ShowChartButton               matlab.ui.control.Button
        TruckSpeedButtonGroup         matlab.ui.container.ButtonGroup
        NorestrictButton              matlab.ui.control.RadioButton
        HighButton                    matlab.ui.control.RadioButton
        LowButton                     matlab.ui.control.RadioButton
        WorkerShiftButtonGroup        matlab.ui.container.ButtonGroup
        DayNightButton                matlab.ui.control.RadioButton
        DayButton                     matlab.ui.control.RadioButton
        TruckAvailabilityButtonGroup  matlab.ui.container.ButtonGroup
        Button_3                      matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        LoadmodelButton               matlab.ui.control.Button
        orLabel                       matlab.ui.control.Label
        UnloadingtimeTextArea         matlab.ui.control.TextArea
        UnloadingtimeTextAreaLabel    matlab.ui.control.Label
        LoadingtimeTextArea           matlab.ui.control.TextArea
        LoadingtimeTextAreaLabel      matlab.ui.control.Label
        DieselpriceUSdollarsperlitreTextArea  matlab.ui.control.TextArea
        DieselpriceUSdollarsperlitreTextAreaLabel  matlab.ui.control.Label
        RunMOOButton                  matlab.ui.control.Button
        NightTextArea                 matlab.ui.control.TextArea
        NightTextAreaLabel            matlab.ui.control.Label
        WorkerpaymentUSdollarperhourLabel  matlab.ui.control.Label
        DayTextArea                   matlab.ui.control.TextArea
        DayTextAreaLabel              matlab.ui.control.Label
        DistancekmTextArea            matlab.ui.control.TextArea
        DistancekmLabel               matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        BacktoworkatTextArea          matlab.ui.control.TextArea
        BacktoworkatTextAreaLabel     matlab.ui.control.Label
        ButtonGroup                   matlab.ui.container.ButtonGroup
        nightButton                   matlab.ui.control.RadioButton
        afternoonButton               matlab.ui.control.RadioButton
        dayButton                     matlab.ui.control.RadioButton
        TimeStampsTextArea            matlab.ui.control.TextArea
        TimeStampsTextAreaLabel       matlab.ui.control.Label
        restingclocktimeatTextArea    matlab.ui.control.TextArea
        restingclocktimeatTextAreaLabel  matlab.ui.control.Label
        UpdateTimetableButton         matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        SelectedSolutionNoEditField   matlab.ui.control.NumericEditField
        SelectedSolutionNoEditFieldLabel  matlab.ui.control.Label
        ShowTimetableButton           matlab.ui.control.Button
        costAX                        matlab.ui.control.UIAxes
        productAX                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        result_table
        fixed_variables
        avail_ind
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunMOOButton
        function RunMOOButtonPushed(app, event)
            diver_pay_day = app.DayTextArea.Value; diver_pay_day = str2double(diver_pay_day{1});
            diver_pay_night = app.NightTextArea.Value; diver_pay_night = str2double(diver_pay_night{1});
            fixed_var.diver_pay = [diver_pay_day diver_pay_night];
            fuel_price = app.DieselpriceUSdollarsperlitreTextArea.Value; fixed_var.petrol_price = str2double(fuel_price{1});
            distance = app.DistancekmTextArea.Value; fixed_var.distance = str2double(distance{1});
            tload = app.LoadingtimeTextArea.Value; fixed_var.loading_time = duration(tload{1});
            tunload = app.UnloadingtimeTextArea.Value; fixed_var.unload_time = duration(tunload{1});
            addpath(genpath('Problems'))
            %persistent truck_sch;
            truck_sch = truckobj(fixed_var);
            [Dec,Obj,~] = platemo('algorithm',@NSGAII,'problem',{@truck,truck_sch});
            real_result = truck_sch.recover(Obj,Dec);
            No = (1:size(real_result,1))';
            app.result_table = [table(No) real_result];
            app.fixed_variables = fixed_var;
            app.UITable.ColumnName = app.result_table.Properties.VariableNames;
            app.UITable.Data = app.result_table;
        end

        % Button pushed function: LoadmodelButton
        function LoadmodelButtonPushed(app, event)
            load('truck_result.mat','real_result')
            addpath(genpath('Problems'))
            %real_result = readtable('real_result.csv');
            No = (1:size(real_result,1))';
            app.result_table = [table(No) real_result];
            fixed_var.diver_pay = [22.64 25.27]; fixed_var.petrol_price = 1.385; fixed_var.distance = 2.575;
            fixed_var.loading_time = duration([0 4 9]); fixed_var.unload_time = duration([0 1 5]);
            app.fixed_variables = fixed_var;
            app.UITable.ColumnName = app.result_table.Properties.VariableNames;
            app.UITable.Data = app.result_table;
            %app.uitTable = uitable(app.RightPanel,'data',app.result_table,'Position',[28,508,618,187],'FontSize',10);
        end

        % Button pushed function: ShowChartButton
        function ShowChartButtonPushed(app, event)
            result = app.result_table;
            sp = app.TruckSpeedButtonGroup.SelectedObject.Text;
            fleet = str2double(app.TruckAvailabilityButtonGroup.SelectedObject.Text);
            if strcmp(app.WorkerShiftButtonGroup.SelectedObject.Text,'Day')
                wshift = 0;
            else
                wshift = 1;
            end
            ind = result.work_shift_state==wshift;
            mid = [21.49 24.13 21.53]; % [loaded speed, day empty speed, night empty speed]
            if strcmp(sp,'Low')
                ind1 = result.day_loaded_speed<mid(1);
                ind2 = result.day_empty_speed<mid(2);
                ind3 = result.night_loaded_speed<mid(1);
                ind4 = result.night_empty_speed<mid(3);
                ind = ind & ind1 & ind2 & ind3 & ind4;
            elseif strcmp(sp,'High')
                ind1 = result.day_loaded_speed>mid(1);
                ind2 = result.day_empty_speed>mid(2);
                ind3 = result.night_loaded_speed>mid(1);
                ind4 = result.night_empty_speed>mid(3);
                ind = ind & ind1 & ind2 & ind3 & ind4;
            end
            ind = ind & result.day_truck_numbers<=fleet;
            if wshift
                ind = ind & result.night_truck_numbers<=fleet;
            end
            app.avail_ind = ind;
            idx = find(ind);
            ind_num = min(10,sum(ind)); % maximum number of bar graphs
            [~,I]=sort(result(ind,:).productions,'descend');
            des_result = result(idx(I(1:ind_num)),:);
            des_name = arrayfun(@num2str, idx(I(1:ind_num)), 'UniformOutput', 0);
            b1 = bar(app.productAX,[minutes(des_result.queuing_time)*10 des_result.productions/1000 des_result.cost/300]);
            legend(app.productAX,'queuing time','productions','cost');
            set(app.productAX, 'xticklabels', des_name);
            [~,I]=sort(result(ind,:).cost);
            inc_result = result(idx(I(1:ind_num)),:);
            des_name = arrayfun(@num2str, idx(I(1:ind_num)), 'UniformOutput', 0);
            %ax2 = uiaxes(app.RightPanel,'Position',[28,108,618,187]);
            b2 = bar(app.costAX,[minutes(inc_result.queuing_time)*10 inc_result.productions/1000 inc_result.cost/300]);
            set(app.costAX, 'xticklabels', des_name);
        end

        % Button pushed function: ShowTimetableButton
        function ShowTimetableButtonPushed(app, event)
            result = app.result_table;
            idx = app.SelectedSolutionNoEditField.Value;
            %rest_t = datetime(string(datetime('15-Oct-2000','Format','d-MMM-y'))+' '+' 11:00:00');
            truck_sch = truckobj(app.fixed_variables);
            tline=truck_sch.time_stamps(result(idx,:),'day','11:00:00');
            app.dayButton.Value = true;
            app.TimeStampsTextArea.Value = tline;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            result = app.result_table;
            idx = app.SelectedSolutionNoEditField.Value;
            truck_sch = truckobj(app.fixed_variables);
            switch selectedButton.Text
                case 'day'
                    app.TimeStampsTextArea.Value = truck_sch.time_stamps(result(idx,:),'day','11:00:00');
                case 'afternoon'
                    app.TimeStampsTextArea.Value = truck_sch.time_stamps(result(idx,:),'afternoon','19:00:00');
                case 'night'
                    app.TimeStampsTextArea.Value = truck_sch.time_stamps(result(idx,:),'night','03:00:00');
            end
        end

        % Button pushed function: UpdateTimetableButton
        function UpdateTimetableButtonPushed(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            rest_t = app.restingclocktimeatTextArea.Value;
            back_t = app.BacktoworkatTextArea.Value;
            %rest_t = duration(rest_t{1});
            result = app.result_table;
            idx = app.SelectedSolutionNoEditField.Value;
            truck_sch = truckobj(app.fixed_variables);
            switch selectedButton.Text
                case 'day'
                    [app.TimeStampsTextArea.Value, app.BacktoworkatTextArea.Value] = truck_sch.time_stamps(result(idx,:),'day',rest_t{1},back_t{1});
                case 'afternoon'
                    app.TimeStampsTextArea.Value = truck_sch.time_stamps(result(idx,:),'afternoon',rest_t{1},back_t{1});
                case 'night'
                    app.TimeStampsTextArea.Value = truck_sch.time_stamps(result(idx,:),'night',rest_t{1},back_t{1});
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {709, 709};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {300, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DistancekmLabel = uilabel(app.LeftPanel);
            app.DistancekmLabel.Text = 'Distance (km)';
            app.DistancekmTextArea = uitextarea(app.LeftPanel);
            app.DistancekmTextArea.Value = {'2.575'};
            app.DayTextAreaLabel = uilabel(app.LeftPanel);
            app.DayTextAreaLabel.Text = 'Day';
            app.DayTextArea = uitextarea(app.LeftPanel);
            app.DayTextArea.Value = {'22.64'};
            app.WorkerpaymentUSdollarperhourLabel = uilabel(app.LeftPanel);
            app.WorkerpaymentUSdollarperhourLabel.Text = {'Worker payment'; '(US dollar per hour)'};
            app.NightTextAreaLabel = uilabel(app.LeftPanel);
            app.NightTextAreaLabel.Text = 'Night';
            app.NightTextArea = uitextarea(app.LeftPanel);
            app.NightTextArea.Value = {'25.27'};
            app.RunMOOButton = uibutton(app.LeftPanel, 'push');
            app.RunMOOButton.ButtonPushedFcn = createCallbackFcn(app, @RunMOOButtonPushed, true);
            app.RunMOOButton.Text = 'Run MOO';
            app.DieselpriceUSdollarsperlitreTextAreaLabel = uilabel(app.LeftPanel);
            app.DieselpriceUSdollarsperlitreTextAreaLabel.Text = {'Diesel price'; '(US dollars per litre)'};
            app.DieselpriceUSdollarsperlitreTextArea = uitextarea(app.LeftPanel);
            app.DieselpriceUSdollarsperlitreTextArea.Value = {'1.385'};
            app.LoadingtimeTextAreaLabel = uilabel(app.LeftPanel);
            app.LoadingtimeTextAreaLabel.Text = 'Loading time';
            app.LoadingtimeTextArea = uitextarea(app.LeftPanel);
            app.LoadingtimeTextArea.Value = {'00:04:09'};
            app.UnloadingtimeTextAreaLabel = uilabel(app.LeftPanel);
            app.UnloadingtimeTextAreaLabel.Text = 'Unloading time';
            app.UnloadingtimeTextArea = uitextarea(app.LeftPanel);
            app.UnloadingtimeTextArea.Value = {'00:01:05'};
            app.orLabel = uilabel(app.LeftPanel);
            app.orLabel.Text = 'or';
            app.LoadmodelButton = uibutton(app.LeftPanel, 'push');
            app.LoadmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmodelButtonPushed, true);
            app.LoadmodelButton.Text = 'Load model';
            app.TruckAvailabilityButtonGroup = uibuttongroup(app.LeftPanel);
            app.TruckAvailabilityButtonGroup.Title = 'Truck Availability';
            app.Button = uiradiobutton(app.TruckAvailabilityButtonGroup);
            app.Button.Text = '3';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.TruckAvailabilityButtonGroup);
            app.Button_2.Text = '4';
            app.Button_3 = uiradiobutton(app.TruckAvailabilityButtonGroup);
            app.Button_3.Text = '5';
            app.WorkerShiftButtonGroup = uibuttongroup(app.LeftPanel);
            app.WorkerShiftButtonGroup.Title = 'Worker Shift';
            app.DayButton = uiradiobutton(app.WorkerShiftButtonGroup);
            app.DayButton.Text = 'Day';
            app.DayButton.Value = true;
            app.DayNightButton = uiradiobutton(app.WorkerShiftButtonGroup);
            app.DayNightButton.Text = 'Day + Night';
            app.TruckSpeedButtonGroup = uibuttongroup(app.LeftPanel);
            app.TruckSpeedButtonGroup.Title = 'Truck Speed';
            app.LowButton = uiradiobutton(app.TruckSpeedButtonGroup);
            app.LowButton.Text = 'Low';
            app.LowButton.Value = true;
            app.HighButton = uiradiobutton(app.TruckSpeedButtonGroup);
            app.HighButton.Text = 'High';
            app.NorestrictButton = uiradiobutton(app.TruckSpeedButtonGroup);
            app.NorestrictButton.Text = 'No restrict';
            app.ShowChartButton = uibutton(app.LeftPanel, 'push');
            app.ShowChartButton.ButtonPushedFcn = createCallbackFcn(app, @ShowChartButtonPushed, true);
            app.ShowChartButton.Text = 'Show Chart';
            app.AlgorithmListBoxLabel = uilabel(app.LeftPanel);
            app.AlgorithmListBoxLabel.Text = 'Algorithm';
            app.AlgorithmListBox = uilistbox(app.LeftPanel);
            app.AlgorithmListBox.Items = {'MOEA/D', 'NSGA-II', 'PESA-II', 'SPEA2'};
            app.AlgorithmListBox.Value = 'MOEA/D';
            app.RightPanel = uipanel(app.GridLayout);
            app.productAX = uiaxes(app.RightPanel);
            xlabel(app.productAX, 'Productions (sorted from high to low)')
            app.costAX = uiaxes(app.RightPanel);
            xlabel(app.costAX, 'Costs (sorted from low to high)')
            app.ShowTimetableButton = uibutton(app.RightPanel, 'push');
            app.ShowTimetableButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTimetableButtonPushed, true);
            app.ShowTimetableButton.Text = 'Show Timetable';
            app.SelectedSolutionNoEditFieldLabel = uilabel(app.RightPanel);
            app.SelectedSolutionNoEditFieldLabel.Text = 'Selected Solution No.';
            app.SelectedSolutionNoEditField = uieditfield(app.RightPanel, 'numeric');
            app.SelectedSolutionNoEditField.Value = 1;
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UpdateTimetableButton = uibutton(app.RightPanel, 'push');
            app.UpdateTimetableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTimetableButtonPushed, true);
            app.UpdateTimetableButton.Text = 'Update Timetable';
            app.restingclocktimeatTextAreaLabel = uilabel(app.RightPanel);
            app.restingclocktimeatTextAreaLabel.Text = 'resting clock time at';
            app.restingclocktimeatTextArea = uitextarea(app.RightPanel);
            app.restingclocktimeatTextArea.Value = {'11:00:00'};
            app.TimeStampsTextAreaLabel = uilabel(app.RightPanel);
            app.TimeStampsTextAreaLabel.Text = 'Time Stamps';
            app.TimeStampsTextArea = uitextarea(app.RightPanel);
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.dayButton = uiradiobutton(app.ButtonGroup);
            app.dayButton.Text = 'day';
            app.dayButton.Value = true;
            app.afternoonButton = uiradiobutton(app.ButtonGroup);
            app.afternoonButton.Text = 'afternoon';
            app.nightButton = uiradiobutton(app.ButtonGroup);
            app.nightButton.Text = 'night';
            app.BacktoworkatTextAreaLabel = uilabel(app.RightPanel);
            app.BacktoworkatTextAreaLabel.Text = 'Back to work at';
            app.BacktoworkatTextArea = uitextarea(app.RightPanel);
            app.BacktoworkatTextArea.Value = {'00:00:01'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = truck_schedule

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end